classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AnalysisMapsDropDown       matlab.ui.control.DropDown
        AnalysisMapsDropDownLabel  matlab.ui.control.Label
        AnalyzeRTIdataButton       matlab.ui.control.Button
        ImageNoSlider              matlab.ui.control.Slider
        ImageNoSliderLabel         matlab.ui.control.Label
        LoadLPButton               matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AcquisitionPath % Description
        LPFileName % Description
        LP % Description
        rgbMean % Description
        drop_down_value % Description
        rgbStd % Description
        rgbMedian 
        rgbMax  % Description
        rawSkewness % Description
        norm_Skewness % Description
        rgbKurtosis % Description
        normalMap % Description
        pixelSizeX = 97% Description
        Dx % Description
        pixelSizeY = 97 % Description
        Dy % Description
        rgbMin % Description
        Dxx % Description
        Dyy % Description
        Dxy % Description
        Kmin % Description
        Kmax % Description
        Kmean % Description
        Kgaussian % Description
        Kmelhum % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadLPButton
        function LoadLPButtonPushed(app, event)
                 try
                    % Open file browser dialog
                    [filename, pathname] = uigetfile('*.lp', 'Select LP File');
                    
                    % Check if user cancelled
                    if filename ~= 0
                        % Store path and filename
                        app.AcquisitionPath = pathname;
                        app.LPFileName = filename;
                        
                        % Load LP file
                        app.LP = loadLP1(fullfile(pathname, filename));

                        % Setup slider
                        app.ImageNoSlider.Limits = [1 length(app.LP.FileNames)];
                        app.ImageNoSlider.Value = 1;
                        app.ImageNoSlider.Enable = 'on';
                        
                        % Get first image from the folder
                        firstImg = imread(fullfile(pathname, app.LP.FileNames{1}));
                        
                        % Display first image
                        imshow(firstImg, 'Parent', app.UIAxes);
                        %title(app.UIAxes, ['Image: ' app.LP.FileNames{1}]);
                        
                        % Show success message
                        msg = sprintf('LP File loaded successfully:\n%s', filename);
                        uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');
                    end
                catch ME
                    % Error handling
                    uialert(app.UIFigure, ME.message, 'Error', 'Icon', 'error');
                end
        end

        % Value changing function: ImageNoSlider
        function ImageNoSliderValueChanging(app, event)
                % Get current slider value
                imageIndex = round(event.Value);


                % Load and display corresponding image
                currentImage = imread(fullfile(app.AcquisitionPath, app.LP.FileNames{imageIndex}));
                imshow(currentImage, 'Parent', app.UIAxes);

                % Update label with current image name
                app.ImageNoSliderLabel.Text= sprintf('Image %d/%d: %s',imageIndex, length(app.LP.FileNames), app.LP.FileNames{imageIndex});
        end

        % Button pushed function: AnalyzeRTIdataButton
        function AnalyzeRTIdataButtonPushed(app, event)
             try                    
                    % Calculate all descriptors
                    app.rgbMean = calculateRGBMean(app.LP);
                    app.rgbStd = calculateRGBStd(app.LP);
%                     app.rgbMedian = calculateRGBMedian(app.LP);
                    app.rgbMax = calculateRGBMax(app.LP);
                    app.rgbMin = calculateRGBMin(app.LP);
                    [app.rawSkewness, app.norm_Skewness] = calculateRGBSkewness(app.LP, app.rgbMean, app.rgbStd);
%                     app.rgbKurtosis = calculateRGBKurtosis(app.LP, app.rgbMean, app.rgbStd);
                     app.normalMap = calculateRGBNormal(app.LP);
                     app.Dx = calculateDirectionalDerivativeX(app.normalMap, app.pixelSizeX);
                     app.Dy = calculateDirectionalDerivativeY(app.normalMap, app.pixelSizeY);
                     [app.Dxx, app.Dyy, app.Dxy] = calculateDoubleDerivatives(app.normalMap, app.pixelSizeY,app.pixelSizeY,app.Dx,app.Dy);
                     %app.Dyy = calculateDirectionalDerivativeY(app.normalMap, app.pixelSizeY);
                     [app.Kmin, app.Kmax] = calculateCriticalCurvatures(app.normalMap, app.pixelSizeX, app.pixelSizeY,  app.Dxx, app.Dyy, app.Dxy);
                     [app.Kmean, app.Kgaussian, app.Kmelhum] = calculateInvariantCurvatures(app.normalMap, app.pixelSizeX, app.pixelSizeY, app.Kmin, app.Kmax);

                    % Display RGB Mean image
                    imshow(app.rgbMean, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'RGB Mean Image');
                    
                    % Show success message
                    msg = sprintf('Feature Map calculated successfully');
                    uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');

            catch ME
                % Error handling
                uialert(app.UIFigure, ME.message, 'Error', 'Icon', 'error');
            end
        end

        % Value changed function: AnalysisMapsDropDown
        function AnalysisMapsDropDownValueChanged(app, event)
            app.drop_down_value = app.AnalysisMapsDropDown.Value;
            switch app.drop_down_value
                case 'Mean'
                    % Display RGB Mean image
                    imshow(app.rgbMean, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Mean Image');
                case 'Median'
                    imshow(app.rgbMedian, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Median Image');
                case 'StDev'
                    imshow(app.rgbStd, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'StDev Image');
                case 'Max'
                    imshow(app.rgbMax, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Max Image');
                case 'Min'
                    imshow(app.rgbMin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Min Image');
                case 'Skewness R'
                    imshow(app.rawSkewness(:,:,1), 'Parent', app.UIAxes2);
                   
                    title(app.UIAxes2, 'Skewness Red');
                case 'Skewness G'
                    imshow(app.rawSkewness(:,:,2), 'Parent', app.UIAxes2);
                  
                    title(app.UIAxes2, 'Skewness Green');
                case 'Skewness B'
                    imshow(app.rawSkewness(:,:,3), 'Parent', app.UIAxes2);
                    
                    title(app.UIAxes2, 'Skewness Blue');
                case 'Kurtosis'
                    imshow(app.rgbKurtosis, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Skewness Image');
                case 'Normal Map'
                    imshow(app.normalMap, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Normal Map');
                case 'Dx'
                    imshow(app.Dx, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Directional Slope X');
                case 'Dy'
                    imshow(app.Dy, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Directional Slope Y');
                case 'Dxx'
                    imshow(app.Dxx, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Dxx');
                case 'Dyy'
                    imshow(app.Dyy, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Dyy');
                case 'Dxy'
                    imshow(app.Dxy, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'Dxy');
                case 'K min'
                    imshow(app.Kmin, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'K min');
                case 'K max'
                    imshow(app.Kmax, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'K max');
                case 'K mean'
                    imshow(app.Kmean, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'K mean');
                case 'K gaussian'
                    imshow(app.Kgaussian, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'K gaussian');
                case 'K melhum'
                    imshow(app.Kmelhum, 'Parent', app.UIAxes2);
                    title(app.UIAxes2, 'K Melhum');
            end
        end

        % Drop down opening function: AnalysisMapsDropDown
        function AnalysisMapsDropDownOpening(app, event)
            
        end

        % Clicked callback: AnalysisMapsDropDown
        function AnalysisMapsDropDownClicked(app, event)
    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RTI Dataset')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Visualization of Feature Maps')
            zlabel(app.UIAxes2, 'Z')
            app.LoadLPButton = uibutton(app.UIFigure, 'push');
            app.LoadLPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLPButtonPushed, true);
            app.LoadLPButton.Text = 'Load LP';
            app.ImageNoSliderLabel = uilabel(app.UIFigure);
            app.ImageNoSliderLabel.Text = 'Image No';
            app.ImageNoSlider = uislider(app.UIFigure);
            app.ImageNoSlider.ValueChangingFcn = createCallbackFcn(app, @ImageNoSliderValueChanging, true);
            app.AnalyzeRTIdataButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeRTIdataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeRTIdataButtonPushed, true);
            app.AnalyzeRTIdataButton.Text = 'Analyze RTI data';
            app.AnalysisMapsDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisMapsDropDownLabel.Text = 'Analysis Maps';
            app.AnalysisMapsDropDown = uidropdown(app.UIFigure);
            app.AnalysisMapsDropDown.Items = {'Mean', 'Median', 'StDev', 'Max', 'Min', 'Skewness R', 'Skewness G', 'Skewness B', 'Normal Map', 'Dx', 'Dy', 'Dxx', 'Dyy', 'Dxy', 'K min', 'K max', 'K mean', 'K gaussian', 'K melhum'};
            app.AnalysisMapsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AnalysisMapsDropDownOpening, true);
            app.AnalysisMapsDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisMapsDropDownValueChanged, true);
            app.AnalysisMapsDropDown.ClickedFcn = createCallbackFcn(app, @AnalysisMapsDropDownClicked, true);
            app.AnalysisMapsDropDown.Value = 'Mean';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end